classdef ToolBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        processBtn                   matlab.ui.control.Button
        play                         matlab.ui.control.Button
        filePicker                   matlab.ui.control.Button
        ListBoxLabel                 matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        TabGroup                     matlab.ui.container.TabGroup
        AlsTab                       matlab.ui.container.Tab
        EarlyreflactionsSliderLabel  matlab.ui.control.Label
        EarlyreflactionsSlider       matlab.ui.control.Slider
        beamformingTab               matlab.ui.container.Tab
        N_arraySliderLabel           matlab.ui.control.Label
        N_arraySlider                matlab.ui.control.Slider
        r_arraySliderLabel           matlab.ui.control.Label
        r_arraySlider                matlab.ui.control.Slider
        N_array_disp                 matlab.ui.control.Label
        binauralTab                  matlab.ui.container.Tab
        N_arraySlider_2Label_2       matlab.ui.control.Label
        binauralN_arraySlider        matlab.ui.control.Slider
        r_arraySlider_2Label         matlab.ui.control.Label
        binauralr_arraySlider        matlab.ui.control.Slider
        binauralN_array_disp         matlab.ui.control.Label
        HRTFfileButton               matlab.ui.control.Button
        hrtffilenameEditField        matlab.ui.control.EditField
        imagebeamformingTab          matlab.ui.container.Tab
        N_arraySlider_2Label         matlab.ui.control.Label
        N_image                      matlab.ui.control.Slider
        selectedLabel                matlab.ui.control.Label
        selected                     matlab.ui.control.ListBox
        plots                        matlab.ui.control.CheckBox
        RoomXLabel_2                 matlab.ui.control.Label
        roomX_2                      matlab.ui.control.Knob
        RoomYLabel_2                 matlab.ui.control.Label
        roomY_2                      matlab.ui.control.Knob
        RoomZLabel_2                 matlab.ui.control.Label
        roomZ_2                      matlab.ui.control.Knob
        UIAxes                       matlab.ui.control.UIAxes
        sourceXSliderLabel           matlab.ui.control.Label
        sourceXSlider                matlab.ui.control.Slider
        sourceYSliderLabel           matlab.ui.control.Label
        sourceYSlider                matlab.ui.control.Slider
        sourceZSliderLabel           matlab.ui.control.Label
        sourceZSlider                matlab.ui.control.Slider
        arrayXLabel                  matlab.ui.control.Label
        arrayXSlider                 matlab.ui.control.Slider
        arrayYLabel                  matlab.ui.control.Label
        arrayYSlider                 matlab.ui.control.Slider
        arrayZLabel                  matlab.ui.control.Label
        arrayZSlider                 matlab.ui.control.Slider
        RoomreflectionLabel          matlab.ui.control.Label
        reflaction                   matlab.ui.control.Slider
        soundView                    matlab.ui.control.UIAxes
        soundviewButtonGroup         matlab.ui.container.ButtonGroup
        timeButton                   matlab.ui.control.RadioButton
        freqButton                   matlab.ui.control.RadioButton
        SwitchLabel                  matlab.ui.control.Label
        DSwitch                      matlab.ui.control.ToggleSwitch
    end

    
    properties (Access = private)
        x % Description
        fs % Description
        path = "ToolBoxApp/data/"
        file
        arrayPos = [5 5 1.7];
        Room = [15.5 9.8 7.5];
        Source = [8.25 3.8 1.7];
        wallsReflection = 0.9;
%         alsargs = containers.Map;
%         beamargs = containers.Map;
%         imageBeam = containers.Map;
%         binauralArgs = containers.Map;
        soundViewopt='time';
%         htrfFile = 'earoHRIR_KU100_Measured_2702Lebedev.mat';
%         htrfPath = "ToolBoxApp/data/"; % Description
        processors = containers.Map;
    end
    
    methods (Access = private)
        
        function proccess(app,to_run)
            disp(to_run);
            if strcmp('Als',to_run)
                  [app.x,app.fs] =  app.processors('Als').process(app.x,app.fs,app.Room,app.Source,app.arrayPos,app.wallsReflection);
%                 [app.x,app.fs] = als(app.x,app.fs,app.Room,app.Source,app.arrayPos,app.wallsReflection,app.alsargs("early reflection"));
            elseif strcmp('beam',to_run)
                 [app.x,app.fs] =  app.processors('beam').process(app.x,app.fs,app.Room,app.Source,app.arrayPos,app.wallsReflection);
            elseif strcmp('image beamforming',to_run)
                [app.x,app.fs] = app.processors('image beamforming').process(app.x,app.fs,app.Room,app.Source,app.arrayPos,app.wallsReflection);
            elseif strcmp('binaural',to_run)
                [app.x,app.fs] = app.processors('binaural').process(app.x,app.fs,app.Room,app.Source,app.arrayPos,app.wallsReflection);
            end
            app.updateSoundView()
        end
        
        function updateRoom(app)
            cla(app.UIAxes);
            PLOTCUBE(app.UIAxes,app.Room,[ 0 0 0],.2,[0 0 0])
            hold( app.UIAxes, 'on' )
            [X,Y,Z] = sphere(5);
            surf(app.UIAxes,(X/2+app.Source(1)),(Y/2+app.Source(2)),(Z/2+app.Source(3)),'FaceColor','g')
            surf(app.UIAxes,(X/2+app.arrayPos(1)),(Y/2+app.arrayPos(2)),(Z/2+app.arrayPos(3)),'FaceColor','r')
            value = app.DSwitch.Value;
            if strcmp(value,'2D')
                 view(app.UIAxes,2)
            else
                view(app.UIAxes,3)

            end
       
        end
        
        function updateSoundView(app)
            if strcmp('time',app.soundViewopt)
                time=(1:length(app.x))/app.fs;
                plot(app.soundView,time,app.x)
                app.soundView.XScale = 'linear';
                app.soundView.XLim = [0,max(time)+1];
            else
                Length_audio=length(app.x);
                df=app.fs/Length_audio;
                frequency_audio=-app.fs/2:df:app.fs/2-df;
                
                FFT_audio_in=fftshift(fft(app.x))/length(fft(app.x));
                semilogx(app.soundView ,frequency_audio,abs(FFT_audio_in));
                app.soundView.XLim = [0,20000];
            end
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startup_script();
            set(groot,'defaultFigureVisible','off')
            updateRoom(app)
%             PLOTCUBE(app.UIAxes,app.beamRoom,[ 0 0 0],.2,[0 0 0])
            app.roomX_2.Value = app.Room(1);
            app.roomY_2.Value = app.Room(2);
            app.roomZ_2.Value = app.Room(3);
            app.sourceXSlider.Value = app.Source(1);
            app.sourceYSlider.Value = app.Source(2);
            app.sourceZSlider.Value = app.Source(3);
            app.arrayXSlider.Value = app.arrayPos(1);
            app.arrayYSlider.Value = app.arrayPos(2);
            app.arrayZSlider.Value = app.arrayPos(3);
%             app.alsargs("early reflection")=20;
%             app.EarlyreflactionsSlider.Value=app.alsargs('early reflection');
%             app.beamargs('N_array')= 4;
%             app.beamargs('r_array')= 0.042;
%             app.N_arraySlider.Value = app.beamargs('N_array');
%             app.r_arraySlider.Value = app.beamargs('r_array');
%             app.imageBeam('N')=4;
            app.reflaction.Value = app.wallsReflection;
%             app.binauralArgs('N_array')= 4;
%             app.binauralArgs('r_array')= 0.042;
%             app.binauralN_arraySlider.Value = app.binauralArgs('N_array');
%             app.binauralr_arraySlider.Value = app.binauralArgs('r_array');
%             app.HTRFfilenameLabel.Text = app.htrfFile;
            app.processors('image beamforming') = imageMethodBeamformingProccessor();
            app.processors('image beamforming').bind(app.N_image,'N','r')
            app.processors('binaural') = binauralProccesor();
            app.processors('binaural').bind(app.binauralN_arraySlider,'N_array','r');
            app.processors('binaural').bind(app.binauralr_arraySlider,'r_array');
            app.processors('binaural').bind(app.hrtffilenameEditField,'HRTFpath');
            app.processors('beam') = beamformingProccessor();
            app.processors('beam').bind(app.N_arraySlider,'N_array','r')
            app.processors('beam').bind(app.r_arraySlider,'r_array')
            app.processors('Als') = alsProcessor();
            app.processors('Als').bind(app.EarlyreflactionsSlider,'early reflections','r')
%             
% b=BaseProcess();
%             b.bind(app.reflaction,'s')
        end

        % Button pushed function: processBtn
        function processBtnPushed(app, event)
            if app.path ~= ""
                app.processBtn.Enable = 'off';
                drawnow;
                    for k=1:length(app.selected.Items)
                    proccess(app,app.selected.Items{k})
                    end
            
                app.processBtn.Enable = 'on';
            else
                
                warndlg('File not found','Invalid File');
            end
       
        end

        % Button pushed function: play
        function playPushed(app, event)
            soundsc(app.x, app.fs);
        end

        % Button pushed function: filePicker
        function filePickerButtonPushed(app, event)
            [app.file,app.path] = uigetfile('*.wav');
%             disp(app.file)
            [app.x,app.fs] = audioread(strcat(app.path,app.file));
            app.updateSoundView()
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.selected.Items = value;
            if any(strcmp(value,'Als'))
                app.AlsTab.Parent = app.TabGroup;
            else
                app.AlsTab.Parent =[];
            end
            if any(strcmp(value,'beam'))
                app.beamformingTab.Parent = app.TabGroup;
            else
                app.beamformingTab.Parent =[];
            end
            if any(strcmp(value,'image beamforming'))
                app.imagebeamformingTab.Parent = app.TabGroup;
            else
                app.imagebeamformingTab.Parent =[];
            end
            if any(strcmp(value,'binaural'))
                app.binauralTab.Parent = app.TabGroup;
            else
                app.binauralTab.Parent =[];
            end
        end

        % Value changed function: roomX_2
        function roomX_2ValueChanged(app, event)
            value = app.roomX_2.Value;
            app.Room(1)=value;
            app.sourceXSlider.Limits = [0,value];
            app.arrayXSlider.Limits = [0,value];
            updateRoom(app)
        end

        % Value changed function: roomY_2
        function roomY_2ValueChanged(app, event)
            value = app.roomY_2.Value;
            app.Room(2)=value;
            app.sourceYSlider.Limits = [0,value];
            app.arrayYSlider.Limits = [0,value];
            updateRoom(app)
        end

        % Value changed function: roomZ_2
        function roomZ_2ValueChanged(app, event)
            value = app.roomZ_2.Value;
            app.Room(3)=value;
            app.sourceZSlider.Limits = [0,value];
            app.arrayZSlider.Limits = [0,value];
            updateRoom(app)
        end

        % Value changed function: plots
        function plotsValueChanged(app, event)
            value = app.plots.Value;
            if value
                set(groot,'defaultFigureVisible','on')
            else
                set(groot,'defaultFigureVisible','off')

            end
        end

        % Value changed function: sourceXSlider
        function sourceXSliderValueChanged(app, event)
            value = app.sourceXSlider.Value;
            app.Source(1) = value;
            app.updateRoom()
        end

        % Value changed function: sourceYSlider
        function sourceYSliderValueChanged(app, event)
            value = app.sourceYSlider.Value;
            app.Source(2) = value;
            app.updateRoom()
        end

        % Value changed function: sourceZSlider
        function sourceZSliderValueChanged(app, event)
            value = app.sourceZSlider.Value;
            app.Source(3) = value;
            app.updateRoom()
        end

        % Value changed function: arrayXSlider
        function arrayXSliderValueChanged(app, event)
            value = app.arrayXSlider.Value;
            app.arrayPos(1) = value;
            app.updateRoom()
        end

        % Value changed function: arrayYSlider
        function arrayYSliderValueChanged(app, event)
            value = app.arrayYSlider.Value;
            app.arrayPos(2) = value;
            app.updateRoom()
        end

        % Value changed function: arrayZSlider
        function arrayZSliderValueChanged(app, event)
            value = app.arrayZSlider.Value;
            app.arrayPos(3) = value;
            app.updateRoom()
        end

        % Value changed function: EarlyreflactionsSlider
        function EarlyreflactionsSliderValueChanged(app, event)
%             app.EarlyreflactionsSlider.Value = round(app.EarlyreflactionsSlider.Value);
%             value = app.EarlyreflactionsSlider.Value;
%             app.alsargs("early reflection")=value;
        end

        % Value changed function: N_arraySlider
        function N_arraySliderValueChanged(app, event)
%             app.N_arraySlider.Value = round(app.N_arraySlider.Value);
%             value = app.N_arraySlider.Value;
%             app.beamargs('N_array') = value;
            app.N_array_disp.Text = num2str(value);
        end

        % Value changed function: r_arraySlider
        function r_arraySliderValueChanged(app, event)
%             value = app.r_arraySlider.Value;
%             app.beamargs('r_array') = value;
        end

        % Selection changed function: soundviewButtonGroup
        function soundviewButtonGroupSelectionChanged(app, event)
            selectedButton = app.soundviewButtonGroup.SelectedObject;
            app.soundViewopt = selectedButton.Text;
            app.updateSoundView()
        end

        % Value changed function: N_image
        function N_imageValueChanged(app, event)
            
%             app.N_image.Value = round(app.N_image.Value);
%             value = app.N_image.Value;
%             app.imageBeam('N') = value;
%             app.N_array_disp.Text = num2str(value);
        end

        % Value changed function: reflaction
        function reflactionValueChanged(app, event)
            value = app.reflaction.Value;
            app.wallsReflection = value;
        end

        % Value changed function: DSwitch
        function DSwitchValueChanged(app, event)
            value = app.DSwitch.Value;
            if strcmp(value,'2D')
                 view(app.UIAxes,2)
            else
                view(app.UIAxes,3)

            end
        end

        % Value changed function: binauralN_arraySlider
        function binauralN_arraySliderValueChanged(app, event)
            
%             app.binauralN_arraySlider.Value = round(app.binauralN_arraySlider.Value);
%             value = app.binauralN_arraySlider.Value;
%             app.binauralArgs('N_array') = value;
%             app.binauralN_arraySlider.Text = num2str(value);
        end

        % Value changed function: binauralr_arraySlider
        function binauralr_arraySliderValueChanged(app, event)
%             value = app.binauralr_arraySlider.Value;
%              app.binauralArgs('r_array') = value;
        end

        % Button pushed function: HRTFfileButton
        function HRTFfileButtonPushed(app, event)
            [htrffile,htrfPath] = uigetfile('*.mat');
            app.hrtffilenameEditField.Value = strcat(htrfPath,htrffile);
            cellfun(app.hrtffilenameEditField.ValueChangedFcn{1},{app.hrtffilenameEditField},{event},app.hrtffilenameEditField.ValueChangedFcn(2))
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.processBtn = uibutton(app.UIFigure, 'push');
            app.processBtn.ButtonPushedFcn = createCallbackFcn(app, @processBtnPushed, true);
            app.processBtn.Text = 'process';
            app.play = uibutton(app.UIFigure, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playPushed, true);
            app.play.Text = 'play';
            app.filePicker = uibutton(app.UIFigure, 'push');
            app.filePicker.ButtonPushedFcn = createCallbackFcn(app, @filePickerButtonPushed, true);
            app.filePicker.Text = 'select File';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Als', 'beam', 'image beamforming', 'binaural'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Als'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AlsTab = uitab(app.TabGroup);
            app.AlsTab.Title = 'Als';
            app.EarlyreflactionsSliderLabel = uilabel(app.AlsTab);
            app.EarlyreflactionsSliderLabel.Text = 'Early reflactions';
            app.EarlyreflactionsSlider = uislider(app.AlsTab);
            app.EarlyreflactionsSlider.Limits = [15 25];
            app.EarlyreflactionsSlider.MajorTicks = [15 16 17 18 19 20 21 22 23 24 25];
            app.EarlyreflactionsSlider.ValueChangedFcn = createCallbackFcn(app, @EarlyreflactionsSliderValueChanged, true);
            app.EarlyreflactionsSlider.MinorTicks = [];
            app.EarlyreflactionsSlider.Value = 20;
            app.beamformingTab = uitab(app.TabGroup);
            app.beamformingTab.Title = 'beamforming';
            app.N_arraySliderLabel = uilabel(app.beamformingTab);
            app.N_arraySliderLabel.Text = 'N_array';
            app.N_arraySlider = uislider(app.beamformingTab);
            app.N_arraySlider.Limits = [1 8];
            app.N_arraySlider.ValueChangedFcn = createCallbackFcn(app, @N_arraySliderValueChanged, true);
            app.N_arraySlider.MinorTicks = [];
            app.N_arraySlider.Value = 4;
            app.r_arraySliderLabel = uilabel(app.beamformingTab);
            app.r_arraySliderLabel.Text = 'r_array';
            app.r_arraySlider = uislider(app.beamformingTab);
            app.r_arraySlider.Limits = [0.03 0.1];
            app.r_arraySlider.ValueChangedFcn = createCallbackFcn(app, @r_arraySliderValueChanged, true);
            app.r_arraySlider.Value = 0.042;
            app.N_array_disp = uilabel(app.beamformingTab);
            app.N_array_disp.Text = '';
            app.binauralTab = uitab(app.TabGroup);
            app.binauralTab.Title = 'binaural';
            app.N_arraySlider_2Label_2 = uilabel(app.binauralTab);
            app.N_arraySlider_2Label_2.Text = 'N_array';
            app.binauralN_arraySlider = uislider(app.binauralTab);
            app.binauralN_arraySlider.Limits = [1 8];
            app.binauralN_arraySlider.ValueChangedFcn = createCallbackFcn(app, @binauralN_arraySliderValueChanged, true);
            app.binauralN_arraySlider.MinorTicks = [];
            app.binauralN_arraySlider.Value = 4;
            app.r_arraySlider_2Label = uilabel(app.binauralTab);
            app.r_arraySlider_2Label.Text = 'r_array';
            app.binauralr_arraySlider = uislider(app.binauralTab);
            app.binauralr_arraySlider.Limits = [0.03 0.1];
            app.binauralr_arraySlider.ValueChangedFcn = createCallbackFcn(app, @binauralr_arraySliderValueChanged, true);
            app.binauralr_arraySlider.Value = 0.042;
            app.binauralN_array_disp = uilabel(app.binauralTab);
            app.binauralN_array_disp.Text = '';
            app.HRTFfileButton = uibutton(app.binauralTab, 'push');
            app.HRTFfileButton.ButtonPushedFcn = createCallbackFcn(app, @HRTFfileButtonPushed, true);
            app.HRTFfileButton.Text = {'HRTF file'; ''};
            app.hrtffilenameEditField = uieditfield(app.binauralTab, 'text');
            app.hrtffilenameEditField.Editable = 'off';
            app.imagebeamformingTab = uitab(app.TabGroup);
            app.imagebeamformingTab.Title = 'image beamforming';
            app.N_arraySlider_2Label = uilabel(app.imagebeamformingTab);
            app.N_arraySlider_2Label.Text = 'N_array';
            app.N_image = uislider(app.imagebeamformingTab);
            app.N_image.Limits = [1 8];
            app.N_image.ValueChangedFcn = createCallbackFcn(app, @N_imageValueChanged, true);
            app.N_image.MinorTicks = [];
            app.N_image.Value = 4;
            app.selectedLabel = uilabel(app.UIFigure);
            app.selectedLabel.Text = 'selected';
            app.selected = uilistbox(app.UIFigure);
            app.selected.Items = {};
            app.selected.Value = {};
            app.plots = uicheckbox(app.UIFigure);
            app.plots.ValueChangedFcn = createCallbackFcn(app, @plotsValueChanged, true);
            app.plots.Text = 'Enable plots';
            app.RoomXLabel_2 = uilabel(app.UIFigure);
            app.RoomXLabel_2.Text = 'RoomX';
            app.roomX_2 = uiknob(app.UIFigure, 'continuous');
            app.roomX_2.Limits = [0 20];
            app.roomX_2.ValueChangedFcn = createCallbackFcn(app, @roomX_2ValueChanged, true);
            app.RoomYLabel_2 = uilabel(app.UIFigure);
            app.RoomYLabel_2.Text = 'RoomY';
            app.roomY_2 = uiknob(app.UIFigure, 'continuous');
            app.roomY_2.Limits = [0 20];
            app.roomY_2.ValueChangedFcn = createCallbackFcn(app, @roomY_2ValueChanged, true);
            app.RoomZLabel_2 = uilabel(app.UIFigure);
            app.RoomZLabel_2.Text = 'RoomZ';
            app.roomZ_2 = uiknob(app.UIFigure, 'continuous');
            app.roomZ_2.Limits = [0 20];
            app.roomZ_2.ValueChangedFcn = createCallbackFcn(app, @roomZ_2ValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Room')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [0 20];
            app.UIAxes.ZLim = [0 20];
            app.sourceXSliderLabel = uilabel(app.UIFigure);
            app.sourceXSliderLabel.Text = 'sourceX';
            app.sourceXSlider = uislider(app.UIFigure);
            app.sourceXSlider.Limits = [0 10];
            app.sourceXSlider.ValueChangedFcn = createCallbackFcn(app, @sourceXSliderValueChanged, true);
            app.sourceYSliderLabel = uilabel(app.UIFigure);
            app.sourceYSliderLabel.Text = 'sourceY';
            app.sourceYSlider = uislider(app.UIFigure);
            app.sourceYSlider.Limits = [0 10];
            app.sourceYSlider.ValueChangedFcn = createCallbackFcn(app, @sourceYSliderValueChanged, true);
            app.sourceZSliderLabel = uilabel(app.UIFigure);
            app.sourceZSliderLabel.Text = 'sourceZ';
            app.sourceZSlider = uislider(app.UIFigure);
            app.sourceZSlider.Limits = [0 10];
            app.sourceZSlider.ValueChangedFcn = createCallbackFcn(app, @sourceZSliderValueChanged, true);
            app.arrayXLabel = uilabel(app.UIFigure);
            app.arrayXLabel.Text = 'arrayX';
            app.arrayXSlider = uislider(app.UIFigure);
            app.arrayXSlider.Limits = [0 10];
            app.arrayXSlider.ValueChangedFcn = createCallbackFcn(app, @arrayXSliderValueChanged, true);
            app.arrayYLabel = uilabel(app.UIFigure);
            app.arrayYLabel.Text = 'arrayY';
            app.arrayYSlider = uislider(app.UIFigure);
            app.arrayYSlider.Limits = [0 10];
            app.arrayYSlider.ValueChangedFcn = createCallbackFcn(app, @arrayYSliderValueChanged, true);
            app.arrayZLabel = uilabel(app.UIFigure);
            app.arrayZLabel.Text = 'arrayZ';
            app.arrayZSlider = uislider(app.UIFigure);
            app.arrayZSlider.Limits = [0 10];
            app.arrayZSlider.ValueChangedFcn = createCallbackFcn(app, @arrayZSliderValueChanged, true);
            app.RoomreflectionLabel = uilabel(app.UIFigure);
            app.RoomreflectionLabel.Text = 'Room reflection';
            app.reflaction = uislider(app.UIFigure);
            app.reflaction.Limits = [0 1];
            app.reflaction.ValueChangedFcn = createCallbackFcn(app, @reflactionValueChanged, true);
            app.reflaction.Value = 0.9;
            app.soundView = uiaxes(app.UIFigure);
            title(app.soundView, 'Title')
            xlabel(app.soundView, 'X')
            ylabel(app.soundView, 'Y')
            app.soundviewButtonGroup = uibuttongroup(app.UIFigure);
            app.soundviewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @soundviewButtonGroupSelectionChanged, true);
            app.soundviewButtonGroup.Title = 'sound view';
            app.timeButton = uiradiobutton(app.soundviewButtonGroup);
            app.timeButton.Text = 'time';
            app.timeButton.Value = true;
            app.freqButton = uiradiobutton(app.soundviewButtonGroup);
            app.freqButton.Text = 'freq';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.DSwitch = uiswitch(app.UIFigure, 'toggle');
            app.DSwitch.Items = {'3D', '2D'};
            app.DSwitch.ValueChangedFcn = createCallbackFcn(app, @DSwitchValueChanged, true);
            app.DSwitch.Value = '3D';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ToolBox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end